classdef FlowWhiskerDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        WhiskerDesignTab                matlab.ui.container.Tab
        mmEditField_6                   matlab.ui.control.NumericEditField
        mmEditField_6Label              matlab.ui.control.Label
        mmEditField_5                   matlab.ui.control.NumericEditField
        mmEditField_5Label              matlab.ui.control.Label
        mmEditField_4                   matlab.ui.control.NumericEditField
        mmEditField_4Label              matlab.ui.control.Label
        FeasibleEditField_3             matlab.ui.control.EditField
        FeasibleEditField_3Label        matlab.ui.control.Label
        WidthwSlider_3                  matlab.ui.control.Slider
        WidthwSlider_3Label             matlab.ui.control.Label
        HeighthSlider                   matlab.ui.control.Slider
        HeighthSliderLabel              matlab.ui.control.Label
        MomentArmrSlider                matlab.ui.control.Slider
        MomentArmrSliderLabel           matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        ShapeButtonGroup                matlab.ui.container.ButtonGroup
        FinButton_2                     matlab.ui.control.RadioButton
        FinButton                       matlab.ui.control.RadioButton
        PlateButton                     matlab.ui.control.RadioButton
        OvalButton                      matlab.ui.control.RadioButton
        RoundButton                     matlab.ui.control.RadioButton
        WhiskerBodyDesignLabel          matlab.ui.control.Label
        SpringDesignTab                 matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        GPaEditField                    matlab.ui.control.NumericEditField
        GPaEditFieldLabel               matlab.ui.control.Label
        mmEditField_11                  matlab.ui.control.NumericEditField
        mmEditField_11Label             matlab.ui.control.Label
        mmEditField_10                  matlab.ui.control.NumericEditField
        mmEditField_9                   matlab.ui.control.NumericEditField
        mmEditField_9Label              matlab.ui.control.Label
        mmEditField_8                   matlab.ui.control.NumericEditField
        mmEditField_8Label              matlab.ui.control.Label
        mmEditField_7                   matlab.ui.control.NumericEditField
        mmEditField_7Label              matlab.ui.control.Label
        MaterialModulusSlider           matlab.ui.control.Slider
        MaterialModulusLabel            matlab.ui.control.Label
        FeasibleEditField_2             matlab.ui.control.EditField
        FeasibleEditField_2Label        matlab.ui.control.Label
        SpringSideLengthsmmEditField    matlab.ui.control.NumericEditField
        SpringSideLengthsmmEditFieldLabel  matlab.ui.control.Label
        thicknesshSlider                matlab.ui.control.Slider
        thicknesshLabel                 matlab.ui.control.Label
        numberofturnsnSlider            matlab.ui.control.Slider
        numberofturnsnSliderLabel       matlab.ui.control.Label
        LengthlSlider                   matlab.ui.control.Slider
        LengthlSliderLabel              matlab.ui.control.Label
        PitchpSlider                    matlab.ui.control.Slider
        PitchpSliderLabel               matlab.ui.control.Label
        WidthwSlider_2                  matlab.ui.control.Slider
        WidthwSlider_2Label             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SpringDesignLabel               matlab.ui.control.Label
        MagneticFieldTab                matlab.ui.container.Tab
        l_rmmEditField                  matlab.ui.control.NumericEditField
        l_rmmEditFieldLabel             matlab.ui.control.Label
        MmmEditField                    matlab.ui.control.NumericEditField
        MmmEditFieldLabel               matlab.ui.control.Label
        rmmEditField                    matlab.ui.control.NumericEditField
        rmmEditFieldLabel               matlab.ui.control.Label
        d_smmEditField                  matlab.ui.control.NumericEditField
        d_smmEditFieldLabel             matlab.ui.control.Label
        RotationLengthSlider            matlab.ui.control.Slider
        RotationLengthSliderLabel       matlab.ui.control.Label
        MagnetRadiusSlider              matlab.ui.control.Slider
        MagnetRadiusSliderLabel         matlab.ui.control.Label
        MagnetHeightSlider              matlab.ui.control.Slider
        MagnetHeightSliderLabel         matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        MagneticFieldDesignLabel        matlab.ui.control.Label
        Distancesensortomagnetd_smmSlider  matlab.ui.control.Slider
        DistancesensortomagnetLabel     matlab.ui.control.Label
        TunedParametersLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentSensorSpecificationsTab  matlab.ui.container.Tab
        UpdatePlotButton                matlab.ui.control.Button
        MaximumVelocitymsEditField      matlab.ui.control.NumericEditField
        MaximumVelocitymsEditFieldLabel  matlab.ui.control.Label
        VelocityatMidSensingRangemsEditField  matlab.ui.control.NumericEditField
        VelocityatMidSensingRangemsEditFieldLabel  matlab.ui.control.Label
        AverageSensitivityLSBmmsEditFieldLabel  matlab.ui.control.Label
        AverageSensitivityLSBmmsEditField  matlab.ui.control.NumericEditField
        CurrentSensorSpecificationsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AchievableSensorSpecsTab        matlab.ui.container.Tab
        UpdateDesignPlotsButton         matlab.ui.control.Button
        DesignAspecttoChangeButtonGroup  matlab.ui.container.ButtonGroup
        MagneticFieldDesignButton       matlab.ui.control.RadioButton
        SpringDesignButton              matlab.ui.control.RadioButton
        SensorDesignButton              matlab.ui.control.RadioButton
        AcheivableSensorSpecificationsLabel  matlab.ui.control.Label
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        FluidButtonGroup                matlab.ui.container.ButtonGroup
        WaterButton                     matlab.ui.control.RadioButton
        AirButton                       matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)
        
        function [theta_x, theta_y] = get_cart_angle(app, pi_w, theta_w, lw)
            theta_x = asin(lw*sin(pi_w)*sin(theta_w)/sqrt((lw*sin(pi_w)*sin(theta_w))^2+(lw*cos(pi_w))^2));
            theta_y = asin(lw*sin(pi_w)*cos(theta_w)/sqrt((lw*sin(pi_w)*cos(theta_w))^2+(lw*cos(pi_w))^2));
            
        end
        
        function [db1, db2] = get_plate_disp(app, pi_w, theta_w, lb)
                W = [0 0 -cos(theta_w); 0 0 -sin(theta_w); cos(theta_w) sin(theta_w) 0];
                R = eye(3) + sin(pi_w)*W + 2*sin(pi_w/2)^2*W*W;
                x1 = [lb, 0, 0];
                y1 = [0, lb, 0];
                D1 = R*x1';
                D2 = R*y1';
                db1 = D1(3,1);
                db2 = D2(3,1);
        end
        
        function [Mx, My] = get_momentw(app, theta_x, theta_y, dbx, dby, lb, w, p, l, h, n, E)
            %%%Bottom plate displacement to moment by serpentine spring
            %%%system
            
            %Define spring variables
            p% = 0.00041;
            t = 0;
            % = 0.001;
            %w = 0.00025;
            %h = 0.0001;
            %n = 6;
            %E = 200.0*10^9; %% Young's modulus
            v = 0.35; 
            Gu = 77.2*10^9;
            Ia = w*h*h*h/12;
            Ib = w*w*w*h/12;
            % Ib=Ia;
            Ja = w*h*h*h*(1/3-0.21*h/w*(1-h*h*h*h/w/w/w/w/12));
            Jb = w*w*w*h*(1/3-0.21*w/h*(1-w*w*w*w/h/h/h/h/12));
            % Jb=Ja;
        
            %%%Define Acquiring Values%%%
            theta = zeros(1);
            phi = zeros(1);
            delta = zeros(1);
            M = zeros(1);
            T = zeros(1);
            Fz = zeros(1);
        
            %%%Equations for C Matrix%%%
            C = zeros(3);
            C(1,1) = (2+n)*p/(E*Ia)+(n*(n-1)*t+2*n*l)/(Gu*Jb);
            C(1,2) = 0;
            C(2,1) = 0;
            C(1,3) = -1*p^2*(2+2*n+n^2/2)/(E*Ia)-p*(1/6*n*(4*n+7)*(n-1)*t+n*(n+2)*l)/(Gu*Jb);
            C(3,1) = C(1,3);
            C(2,2) = p*(2+n)/Gu/Ja+((2*n+1)*l+n*(n-1)*t)/E/Ib;
            C(2,3) = -1/(Gu*Ja)*p*t*(n/2)+1/(E*Ib)*(-1/2*n*(n-1)*t^2-n*l*t);
            C(3,2) = 0;
            C(3,3) = 1/(E*Ia)*p^3*(1/3*(2+n)^3)+1/(Gu*Ja)*p*(1/6*n*(2*n-1)*(n-1)*t^2+n*(n-1)*l*t+n*l^2)...
                +1/(E*Ib)*(1/6*n^2*(n-1)^2*t^3+2/3*n*l^3+n*(n-1)*l^2*t+(2/3*n^3-n^2+1/3*n)*l*t^2)...
                +1/(Gu*Jb)*p^2*(1/2*n*(n-1)*(n^2+3*n+3)*t+(2/3*n^3+2*n^2+7/3*n)*l);
        
            %%Calculate Mx, My
            C1 = inv(C);
            dis = [theta_x; theta_y; dbx; dby];
            C2 = [C1(1,2) C1(1,1) C1(1,3) 0; C1(2,2) C1(2,1) C1(2,3) 0; C1(3,2) C1(3,1) C1(3,3) 0; C1(1,1) C1(1,2) 0 C1(1,3); C1(2,1) C1(2,2) 0 C1(2,3); C1(3,1) C1(3,2) 0 C1(3,3)];
            LB = [0 2 0 2 0 2*lb;2 0 2*lb 0 2 0];
            M = LB*C2*dis;
            Mx = M(1,1);
            My = M(2,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UpdateDesignButtonPushed(app)
             % Predfined values
            lw = 0.01; 
            lb = 0.0005; %Bottom plate edge lengt/2
            theta_w=0

            % Get values from the app

            % Fin Design
            Moment_Arm=app.MomentArmrSlider.Value/1000 % in mm
            FinHeight=app.HeighthSlider.Value/1000
            FinWidth=app.WidthwSlider_3.Value/1000
            FinHeight-Moment_Arm*2


            if app.RoundButton.Value
                C_d=1.1
            elseif app.OvalButton.Value
                C_d=1.1
            else
                C_d=1.34
            end

            % Spring Design
            SpringWidth=app.WidthwSlider_2.Value/1000; % mm
            SpringPitch=app.PitchpSlider.Value/1000; % mm
            SpringLength=app.LengthlSlider.Value/1000; % mmm
            SpringN=round(app.numberofturnsnSlider.Value); %mm
            SpringThickness=app.thicknesshSlider.Value/1000; % mm
            SpringModulus=app.MaterialModulusSlider.Value*10^9;

            % Magnetic Field Design
            DistanceSensor=app.Distancesensortomagnetd_smmSlider.Value/1000;
            MagnetRotationLength=app.RotationLengthSlider.Value/1000;
            MagnetRadius=app.MagnetRadiusSlider.Value/1000;
            MagnetZLength=app.MagnetHeightSlider.Value/2000;


            %Fluid
            Fluid=app.FluidButtonGroup
            if app.AirButton.Value
                rho=1.293 %kg m^-3
            else
                rho=997 %kg m^-3
            end        


            % Mid Point Velocity Graph
            DMin=10;
            DMax=40;
            rmin=0.5;
            rmax=3.5;
            stepsize=1;
            stepsize2=0.1*stepsize;
            

            % Expected Moment caused by 7.5 degrees rotation
            phi=7.5*3.14159/180;
            [theta_x, theta_y]= get_cart_angle(phi,theta_w,lw);
            [db1, db2]=get_plate_disp(phi, theta_w, lb);
            [Mx, My] = get_moment(theta_x, theta_y, db1, db2, lb);
            
            % Expected Velocity caused by 20 degrees rotation under different design
            % parameters
            j=1;
            for Depth= DMin : stepsize : DMax
                Moment_Arm=(60-1/2*Depth+3);
                i=1;
                for r = rmin: stepsize2: rmax
                    A=r*Depth;
                    v=sqrt(2*My/(rho*C_d*A/1000^2*Moment_Arm/1000*cos(phi)));
                    v2Matrix(i,j)=v;
                    i=i+1;
                end
                j=j+1;
            end
            % Generate the heat map
            %minValue=min(v2Matrix,[],'all'); % Minimum Resolution of Interest [LSB/mm/s]
            %maxValue=max(v2Matrix,[],'all'); % Maximum Resolution of Interest [LSB/ mm/s]
            minValue=0.2;
            maxValue=1.2;
            a=linspace(DMin,DMax,round((40-10)/stepsize+1));
            ma=linspace(rmin,rmax,round((rmax-rmin)/(stepsize2)+1));
            imagesc(a,ma,v2Matrix,[minValue maxValue])
            hold on
            
            % Artistic Affects on the Heat Map
            set(gca,'fontsize', 16)
            hold on
            set(gca,'YDir','normal')
            c=colormap(jet);
            cbar = colorbar();
            clim([minValue,maxValue])
            
            hold on
            ylabel('diameter [mm]')
            xlabel('Depth (D_{im}) [mm]')
            set(gca,'ColorScale','linear')
            ylabel(cbar,{'Expected Mid-Point Velocity [m/s]'},'Rotation',270);
            box on
            axis square
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            % Predfined values
            lw = 0.01; 
            lb = 0.0005; %Bottom plate edge length/2
            ls = 1/1000; %Side lengths on the square

            % Get values from the app

            % Fin Design
            Moment_Arm=app.MomentArmrSlider.Value/1000 % in mm
            FinHeight=app.HeighthSlider.Value/1000
            FinWidth=app.WidthwSlider_3.Value/1000

            % Show Values
            app.mmEditField_4.Value=Moment_Arm*1000;
            app.mmEditField_5.Value=FinHeight*1000;
            app.mmEditField_6.Value=FinWidth*1000;

            % Determine Fin Feasibility
            if (FinHeight >= Moment_Arm*2)
                app.FeasibleEditField_3.Value='NO';
                app.FeasibleEditField_3.FontColor=[1,0,0];
            else
                app.FeasibleEditField_3.Value='YES';
                app.FeasibleEditField_3.FontColor=[0,1,.5];
            end

            % Fin Shape            
            if app.RoundButton.Value
                C_d=1.1;
            elseif app.OvalButton.Value
                C_d=1.1;
            else
                C_d=1.34;
            end

            % Spring Design
            SpringWidth=app.WidthwSlider_2.Value/1000; % mm
            app.mmEditField_7.Value=SpringWidth*1000;

            SpringPitch=app.PitchpSlider.Value/1000; % mm
            app.mmEditField_8.Value=SpringPitch*1000;

            SpringLength=app.LengthlSlider.Value/1000; % mmm
            app.mmEditField_9.Value=SpringLength*1000;

            SpringN=round(app.numberofturnsnSlider.Value); 
            app.mmEditField_10.Value=SpringN;

            SpringThickness=app.thicknesshSlider.Value/1000; % mm
            app.mmEditField_11.Value=SpringThickness*1000;

            SpringModulus=app.MaterialModulusSlider.Value*10^9;
            app.GPaEditField.Value=SpringModulus/10^9;

            app.SpringSideLengthsmmEditField.Value=2*(lb+ls+SpringN*(SpringPitch+SpringWidth))*1000;%mm


            % Magnetic Field Design
            DistanceSensor=app.Distancesensortomagnetd_smmSlider.Value/1000;
            app.d_smmEditField.Value=DistanceSensor*1000;

            MagnetRotationLength=app.RotationLengthSlider.Value/1000;
            app.rmmEditField.Value=MagnetRotationLength*1000;

            MagnetRadius=app.MagnetRadiusSlider.Value/1000;
            app.MmmEditField.Value=MagnetRadius*1000;

            MagnetZLength=app.MagnetHeightSlider.Value/2000;
            app.l_rmmEditField.Value=MagnetZLength*2000;

            if (SpringPitch<SpringWidth)
                app.FeasibleEditField_2.Value='NO';
                app.FeasibleEditField_2.FontColor=[1,0,0];
            else
                app.FeasibleEditField_2.Value='YES';
                app.FeasibleEditField_2.FontColor=[0,1,.5];
            end


            % Fluid Material
            if app.AirButton.Value
                rho=1.293 %kg m^-3
            else
                rho=997 %kg m^-3
            end

            % Area based on shape
            A=FinWidth*FinHeight;

            % Create Graph Data
            Spacing= 0.5;
            NumberSpacing = 40/Spacing+1;
            Bx=zeros(NumberSpacing,1);
            v=zeros(NumberSpacing,1);
            j=1;

            for phi=-20:Spacing:20
                phi2=phi*3.14159/180;
                [theta_x, theta_y]= get_cart_angle(app,phi2,0,lw);
                [db1, db2]=get_plate_disp(app, phi2, 0, lb);

                [Mx, My] = get_momentw(app, theta_x, theta_y, db1, db2, lb, SpringWidth, SpringPitch, SpringLength, SpringThickness, SpringN, SpringModulus);
                
                
                % Cd based on shape

                v(j)=sqrt(abs(2*My/(rho*C_d*A*Moment_Arm*cos(phi2))))*sign(phi);
                [PSx, PSy, PSz] = senP_from_mag(phi2, 0, MagnetRotationLength, MagnetZLength, DistanceSensor);
                [BMx, BMy, BMz] = CylMag(PSx,PSy,PSz,MagnetRadius,MagnetZLength);
                [Bx(j), By, Bz(j)] = magB_to_senB(BMx, BMy, BMz, phi2, 0);
                % Velocity Mid Sensing Range
                j=j+1;


            end
            plot(app.UIAxes,v, -1000.*Bx,'LineWidth',5)
            % Add the max Velocity
            app.MaximumVelocitymsEditField.Value=v(j-1);
            app.VelocityatMidSensingRangemsEditField.Value=v(round((j-1)*.75));
            app.AverageSensitivityLSBmmsEditField.Value=5*Bx(j-1)/v(j-1);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {510, 510};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TunedParametersLabel = uilabel(app.LeftPanel);
            app.TunedParametersLabel.Text = 'Tuned Parameters';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.WhiskerDesignTab = uitab(app.TabGroup);
            app.WhiskerDesignTab.Title = 'Whisker Design';
            app.WhiskerBodyDesignLabel = uilabel(app.WhiskerDesignTab);
            app.WhiskerBodyDesignLabel.Text = 'Whisker Body Design';
            app.ShapeButtonGroup = uibuttongroup(app.WhiskerDesignTab);
            app.ShapeButtonGroup.Title = 'Shape';
            app.RoundButton = uiradiobutton(app.ShapeButtonGroup);
            app.RoundButton.Text = 'Round';
            app.RoundButton.Value = true;
            app.OvalButton = uiradiobutton(app.ShapeButtonGroup);
            app.OvalButton.Text = 'Oval';
            app.PlateButton = uiradiobutton(app.ShapeButtonGroup);
            app.PlateButton.Text = 'Plate';
            app.FinButton = uiradiobutton(app.ShapeButtonGroup);
            app.FinButton.Text = '4-Fin';
            app.FinButton_2 = uiradiobutton(app.ShapeButtonGroup);
            app.FinButton_2.Text = '8-Fin';
            app.Image4 = uiimage(app.WhiskerDesignTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Fin Design.png');
            app.MomentArmrSliderLabel = uilabel(app.WhiskerDesignTab);
            app.MomentArmrSliderLabel.Text = 'Moment Arm (r)';
            app.MomentArmrSlider = uislider(app.WhiskerDesignTab);
            app.MomentArmrSlider.Limits = [10 100];
            app.MomentArmrSlider.MajorTicks = [10 25 40 55 70 85 100];
            app.MomentArmrSlider.Value = 20;
            app.HeighthSliderLabel = uilabel(app.WhiskerDesignTab);
            app.HeighthSliderLabel.Text = 'Height (h)';
            app.HeighthSlider = uislider(app.WhiskerDesignTab);
            app.HeighthSlider.Limits = [20 150];
            app.HeighthSlider.MajorTicks = [20 50 80 110 140];
            app.HeighthSlider.Value = 20;
            app.WidthwSlider_3Label = uilabel(app.WhiskerDesignTab);
            app.WidthwSlider_3Label.Text = 'Width (w)';
            app.WidthwSlider_3 = uislider(app.WhiskerDesignTab);
            app.WidthwSlider_3.Limits = [1 20];
            app.WidthwSlider_3.MajorTicks = [1 5 10 15 20];
            app.WidthwSlider_3.Value = 5;
            app.FeasibleEditField_3Label = uilabel(app.WhiskerDesignTab);
            app.FeasibleEditField_3Label.Text = 'Feasible';
            app.FeasibleEditField_3 = uieditfield(app.WhiskerDesignTab, 'text');
            app.mmEditField_4Label = uilabel(app.WhiskerDesignTab);
            app.mmEditField_4Label.Text = 'mm';
            app.mmEditField_4 = uieditfield(app.WhiskerDesignTab, 'numeric');
            app.mmEditField_5Label = uilabel(app.WhiskerDesignTab);
            app.mmEditField_5Label.Text = 'mm';
            app.mmEditField_5 = uieditfield(app.WhiskerDesignTab, 'numeric');
            app.mmEditField_6Label = uilabel(app.WhiskerDesignTab);
            app.mmEditField_6Label.Text = 'mm';
            app.mmEditField_6 = uieditfield(app.WhiskerDesignTab, 'numeric');
            app.SpringDesignTab = uitab(app.TabGroup);
            app.SpringDesignTab.Title = 'Spring Design';
            app.SpringDesignLabel = uilabel(app.SpringDesignTab);
            app.SpringDesignLabel.Text = 'Spring Design';
            app.Image2 = uiimage(app.SpringDesignTab);
            app.Image2.ImageSource = 'CroppedSpring.png';
            app.WidthwSlider_2Label = uilabel(app.SpringDesignTab);
            app.WidthwSlider_2Label.Text = 'Width (w)';
            app.WidthwSlider_2 = uislider(app.SpringDesignTab);
            app.WidthwSlider_2.Limits = [0.1 2];
            app.WidthwSlider_2.MajorTicks = [0.1 0.5 1 1.5 2];
            app.WidthwSlider_2.Value = 0.25;
            app.PitchpSliderLabel = uilabel(app.SpringDesignTab);
            app.PitchpSliderLabel.Text = 'Pitch (p)';
            app.PitchpSlider = uislider(app.SpringDesignTab);
            app.PitchpSlider.Limits = [0.25 4];
            app.PitchpSlider.Value = 0.41;
            app.LengthlSliderLabel = uilabel(app.SpringDesignTab);
            app.LengthlSliderLabel.Text = 'Length (l)';
            app.LengthlSlider = uislider(app.SpringDesignTab);
            app.LengthlSlider.Limits = [0.25 7.5];
            app.LengthlSlider.Value = 1;
            app.numberofturnsnSliderLabel = uilabel(app.SpringDesignTab);
            app.numberofturnsnSliderLabel.Text = {'number of '; 'turns (n)'};
            app.numberofturnsnSlider = uislider(app.SpringDesignTab);
            app.numberofturnsnSlider.Limits = [3 16];
            app.numberofturnsnSlider.MajorTicks = [3 5 7 9 11 13 15];
            app.numberofturnsnSlider.MinorTicks = [3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.numberofturnsnSlider.Value = 6;
            app.thicknesshLabel = uilabel(app.SpringDesignTab);
            app.thicknesshLabel.Text = 'thickness (h)';
            app.thicknesshSlider = uislider(app.SpringDesignTab);
            app.thicknesshSlider.Limits = [0.01 1];
            app.thicknesshSlider.Value = 0.1;
            app.SpringSideLengthsmmEditFieldLabel = uilabel(app.SpringDesignTab);
            app.SpringSideLengthsmmEditFieldLabel.Text = {'Spring Side '; 'Lengths (mm)'};
            app.SpringSideLengthsmmEditField = uieditfield(app.SpringDesignTab, 'numeric');
            app.FeasibleEditField_2Label = uilabel(app.SpringDesignTab);
            app.FeasibleEditField_2Label.Text = 'Feasible';
            app.FeasibleEditField_2 = uieditfield(app.SpringDesignTab, 'text');
            app.MaterialModulusLabel = uilabel(app.SpringDesignTab);
            app.MaterialModulusLabel.Text = 'Material Modulus';
            app.MaterialModulusSlider = uislider(app.SpringDesignTab);
            app.MaterialModulusSlider.Limits = [1 1000];
            app.MaterialModulusSlider.Value = 200;
            app.mmEditField_7Label = uilabel(app.SpringDesignTab);
            app.mmEditField_7Label.Text = 'mm';
            app.mmEditField_7 = uieditfield(app.SpringDesignTab, 'numeric');
            app.mmEditField_8Label = uilabel(app.SpringDesignTab);
            app.mmEditField_8Label.Text = 'mm';
            app.mmEditField_8 = uieditfield(app.SpringDesignTab, 'numeric');
            app.mmEditField_9Label = uilabel(app.SpringDesignTab);
            app.mmEditField_9Label.Text = 'mm';
            app.mmEditField_9 = uieditfield(app.SpringDesignTab, 'numeric');
            app.mmEditField_10 = uieditfield(app.SpringDesignTab, 'numeric');
            app.mmEditField_11Label = uilabel(app.SpringDesignTab);
            app.mmEditField_11Label.Text = 'mm';
            app.mmEditField_11 = uieditfield(app.SpringDesignTab, 'numeric');
            app.GPaEditFieldLabel = uilabel(app.SpringDesignTab);
            app.GPaEditFieldLabel.Text = 'GPa';
            app.GPaEditField = uieditfield(app.SpringDesignTab, 'numeric');
            app.Image5 = uiimage(app.SpringDesignTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Picture2.png');
            app.MagneticFieldTab = uitab(app.TabGroup);
            app.MagneticFieldTab.Title = 'Magnetic Field ';
            app.DistancesensortomagnetLabel = uilabel(app.MagneticFieldTab);
            app.DistancesensortomagnetLabel.Text = 'Distance sensor to magnet';
            app.Distancesensortomagnetd_smmSlider = uislider(app.MagneticFieldTab);
            app.Distancesensortomagnetd_smmSlider.Limits = [0.1 10];
            app.Distancesensortomagnetd_smmSlider.Value = 2.45;
            app.MagneticFieldDesignLabel = uilabel(app.MagneticFieldTab);
            app.MagneticFieldDesignLabel.Text = 'Magnetic Field Design';
            app.Image3 = uiimage(app.MagneticFieldTab);
            app.Image3.ImageSource = 'Magnetic Field Instructional.jpg';
            app.MagnetHeightSliderLabel = uilabel(app.MagneticFieldTab);
            app.MagnetHeightSliderLabel.Text = 'Magnet Height';
            app.MagnetHeightSlider = uislider(app.MagneticFieldTab);
            app.MagnetHeightSlider.Limits = [0.25 3];
            app.MagnetHeightSlider.Value = 1;
            app.MagnetRadiusSliderLabel = uilabel(app.MagneticFieldTab);
            app.MagnetRadiusSliderLabel.Text = {'Magnet Radius'; ''};
            app.MagnetRadiusSlider = uislider(app.MagneticFieldTab);
            app.MagnetRadiusSlider.Limits = [0.25 3];
            app.MagnetRadiusSlider.Value = 1;
            app.RotationLengthSliderLabel = uilabel(app.MagneticFieldTab);
            app.RotationLengthSliderLabel.Text = {'Rotation Length'; ' '};
            app.RotationLengthSlider = uislider(app.MagneticFieldTab);
            app.RotationLengthSlider.Limits = [0.25 3];
            app.RotationLengthSlider.Value = 1;
            app.d_smmEditFieldLabel = uilabel(app.MagneticFieldTab);
            app.d_smmEditFieldLabel.Text = 'd_s (mm)';
            app.d_smmEditField = uieditfield(app.MagneticFieldTab, 'numeric');
            app.rmmEditFieldLabel = uilabel(app.MagneticFieldTab);
            app.rmmEditFieldLabel.Text = 'r (mm)';
            app.rmmEditField = uieditfield(app.MagneticFieldTab, 'numeric');
            app.MmmEditFieldLabel = uilabel(app.MagneticFieldTab);
            app.MmmEditFieldLabel.Text = 'M (mm)';
            app.MmmEditField = uieditfield(app.MagneticFieldTab, 'numeric');
            app.l_rmmEditFieldLabel = uilabel(app.MagneticFieldTab);
            app.l_rmmEditFieldLabel.Text = 'l_r (mm)';
            app.l_rmmEditField = uieditfield(app.MagneticFieldTab, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.FluidButtonGroup = uibuttongroup(app.RightPanel);
            app.FluidButtonGroup.Title = 'Fluid';
            app.AirButton = uiradiobutton(app.FluidButtonGroup);
            app.AirButton.Text = 'Air';
            app.AirButton.Value = true;
            app.WaterButton = uiradiobutton(app.FluidButtonGroup);
            app.WaterButton.Text = 'Water';
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.CurrentSensorSpecificationsTab = uitab(app.TabGroup2);
            app.CurrentSensorSpecificationsTab.Title = 'Current Sensor Specifications';
            app.UIAxes = uiaxes(app.CurrentSensorSpecificationsTab);
            xlabel(app.UIAxes, 'Velocity (m/s)')
            ylabel(app.UIAxes, 'Magnetic Field (mT)')
            app.CurrentSensorSpecificationsLabel = uilabel(app.CurrentSensorSpecificationsTab);
            app.CurrentSensorSpecificationsLabel.Text = 'Current Sensor Specifications';
            app.AverageSensitivityLSBmmsEditField = uieditfield(app.CurrentSensorSpecificationsTab, 'numeric');
            app.AverageSensitivityLSBmmsEditFieldLabel = uilabel(app.CurrentSensorSpecificationsTab);
            app.AverageSensitivityLSBmmsEditFieldLabel.Text = {'Average Sensitivity '; '(LSB/(m/s))'};
            app.VelocityatMidSensingRangemsEditFieldLabel = uilabel(app.CurrentSensorSpecificationsTab);
            app.VelocityatMidSensingRangemsEditFieldLabel.Text = {'Velocity at Mid Sensing Range'; '(m/s)'};
            app.VelocityatMidSensingRangemsEditField = uieditfield(app.CurrentSensorSpecificationsTab, 'numeric');
            app.MaximumVelocitymsEditFieldLabel = uilabel(app.CurrentSensorSpecificationsTab);
            app.MaximumVelocitymsEditFieldLabel.Text = {'Maximum Velocity'; '(m/s)'};
            app.MaximumVelocitymsEditField = uieditfield(app.CurrentSensorSpecificationsTab, 'numeric');
            app.UpdatePlotButton = uibutton(app.CurrentSensorSpecificationsTab, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.AchievableSensorSpecsTab = uitab(app.TabGroup2);
            app.AchievableSensorSpecsTab.Title = 'Achievable Sensor Specs';
            app.UIAxes2 = uiaxes(app.AchievableSensorSpecsTab);
            title(app.UIAxes2, 'Sensitivity')
            xlabel(app.UIAxes2, 'width')
            ylabel(app.UIAxes2, 'height')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.AchievableSensorSpecsTab);
            title(app.UIAxes2_2, 'Mid Speed Velocity')
            xlabel(app.UIAxes2_2, 'width')
            ylabel(app.UIAxes2_2, 'height')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.AchievableSensorSpecsTab);
            title(app.UIAxes2_3, 'Maximum Velocity')
            xlabel(app.UIAxes2_3, 'width')
            ylabel(app.UIAxes2_3, 'height')
            zlabel(app.UIAxes2_3, 'Z')
            app.AcheivableSensorSpecificationsLabel = uilabel(app.AchievableSensorSpecsTab);
            app.AcheivableSensorSpecificationsLabel.Text = 'Acheivable Sensor Specifications';
            app.DesignAspecttoChangeButtonGroup = uibuttongroup(app.AchievableSensorSpecsTab);
            app.DesignAspecttoChangeButtonGroup.Title = 'Design Aspect to Change';
            app.SensorDesignButton = uiradiobutton(app.DesignAspecttoChangeButtonGroup);
            app.SensorDesignButton.Text = 'Sensor Design';
            app.SensorDesignButton.Value = true;
            app.SpringDesignButton = uiradiobutton(app.DesignAspecttoChangeButtonGroup);
            app.SpringDesignButton.Text = 'Spring Design';
            app.MagneticFieldDesignButton = uiradiobutton(app.DesignAspecttoChangeButtonGroup);
            app.MagneticFieldDesignButton.Text = 'Magnetic Field Design';
            app.UpdateDesignPlotsButton = uibutton(app.AchievableSensorSpecsTab, 'push');
            app.UpdateDesignPlotsButton.Text = 'Update Design Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlowWhiskerDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @UpdateDesignButtonPushed)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end